classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_Input                       matlab.ui.container.Tab
        BrowseButton                    matlab.ui.control.Button
        ChooseplatemapEditField         matlab.ui.control.EditField
        TabGroup3                       matlab.ui.container.TabGroup
        Plate1Tab                       matlab.ui.container.Tab
        PathtoImagesEditField           matlab.ui.control.EditField
        UITable                         matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        PathtoImagesLabel               matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        RowsLabel                       matlab.ui.control.Label
        ColumnsLabel                    matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        FieldsLabel_2                   matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        TimepointsLabel_3               matlab.ui.control.Label
        Button_29                       matlab.ui.control.Button
        PlateMetadataLabel              matlab.ui.control.Label
        PlateMapLabel_2                 matlab.ui.control.Label
        FilterInputLabel_2              matlab.ui.control.Label
        ImageChannelsLabel              matlab.ui.control.Label
        Button_30                       matlab.ui.control.Button
        Button_31                       matlab.ui.control.Button
        Button_32                       matlab.ui.control.Button
        ListBox_2                       matlab.ui.control.ListBox
        NumberofImagesLabel             matlab.ui.control.Label
        EditField_5                     matlab.ui.control.EditField
        Button_BrowseHelp               matlab.ui.control.Button
        ChooseplatemaporsavedstateLabel  matlab.ui.control.Label
        Tab_Display                     matlab.ui.container.Tab
        DisplaySettingsLabel            matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ImageSelectionLabel             matlab.ui.control.Label
        ChannelsLabel                   matlab.ui.control.Label
        SegmentOverlayLabel             matlab.ui.control.Label
        MetricOverlayLabel              matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        Button_16                       matlab.ui.control.Button
        Button_17                       matlab.ui.control.Button
        Button_18                       matlab.ui.control.Button
        ColumnDropDownLabel             matlab.ui.control.Label
        ColumnDropDown                  matlab.ui.control.DropDown
        FieldDropDownLabel              matlab.ui.control.Label
        TimepointDropDownLabel          matlab.ui.control.Label
        FieldDropDown                   matlab.ui.control.DropDown
        TimepointDropDown               matlab.ui.control.DropDown
        Button_SaveFigure               matlab.ui.control.Button
        RowDropDownLabel                matlab.ui.control.Label
        RowDropDown                     matlab.ui.control.DropDown
        ExperimentDropDownLabel         matlab.ui.control.Label
        ExperimentDropDown              matlab.ui.control.DropDown
        PlateDropDownLabel              matlab.ui.control.Label
        PlateDropDown                   matlab.ui.control.DropDown
        AtMagnificationSpinnerLabel     matlab.ui.control.Label
        AtMagnificationSpinner          matlab.ui.control.Spinner
        DisplayMeasureDropDown          matlab.ui.control.DropDown
        DisplayMeasureCheckBox          matlab.ui.control.CheckBox
        DisplayMeasureColorButton       matlab.ui.control.Button
        DisplayMeasureFontSize          matlab.ui.control.Spinner
        Button_SaveFigure_2             matlab.ui.control.Button
        MovieatFPSSpinnerLabel          matlab.ui.control.Label
        MovieatFPSSpinner               matlab.ui.control.Spinner
        MontageMovieCheckBox            matlab.ui.control.CheckBox
        Tab_Preprocess                  matlab.ui.container.Tab
        PreprocessInputImagesLabel      matlab.ui.control.Label
        Button_AddProprocess            matlab.ui.control.Button
        AddapreprocesssteptogetstartedLabel  matlab.ui.control.Label
        Tab_Segment                     matlab.ui.container.Tab
        Button_AddSegment               matlab.ui.control.Button
        SegmentationSettingsLabel_2     matlab.ui.control.Label
        AddasegmenttogetstartedLabel    matlab.ui.control.Label
        Tab_Measure                     matlab.ui.container.Tab
        Button_StartProcessing          matlab.ui.control.Button
        ProcessingLogTextArea           matlab.ui.control.TextArea
        ProcessingLogTextAreaLabel      matlab.ui.control.Label
        PrimarySegmentDropDownLabel     matlab.ui.control.Label
        PrimarySegmentDropDown          matlab.ui.control.DropDown
        SelectMeasurementsLabel         matlab.ui.control.Label
        Button_PrimarySegmentHelp       matlab.ui.control.Button
        ProgressSlider                  matlab.ui.control.Slider
        Button_AddMeasure               matlab.ui.control.Button
        CheckBox_Parallel               matlab.ui.control.CheckBox
        CheckBox_TestRun                matlab.ui.control.CheckBox
        Button_ViewMeasurements         matlab.ui.control.Button
        Button_ExportMeasurements       matlab.ui.control.Button
        AddameasurementtogetstartedLabel  matlab.ui.control.Label
        Tab_Track                       matlab.ui.container.Tab
        Tab_Analyze                     matlab.ui.container.Tab
        AnalyzeYourResultsLabel         matlab.ui.control.Label
        AddananalysistogetstartedLabel  matlab.ui.control.Label
        Button_AddAnalysis              matlab.ui.control.Button
        Button_RunAllAnalysis           matlab.ui.control.Button
        SegmentLabel                    matlab.ui.control.Label
        MadewithinTorontoLabel          matlab.ui.control.Label
        Button_Segment                  matlab.ui.control.Button
        MeasureLabel                    matlab.ui.control.Label
        TrackLabel                      matlab.ui.control.Label
        AnalyzeLabel                    matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        Button_Input                    matlab.ui.control.Button
        Button_Plot                     matlab.ui.control.Button
        Button_Track                    matlab.ui.control.Button
        Button_Measure                  matlab.ui.control.Button
        SingleCellAnalysisWorkflowLabel  matlab.ui.control.Label
        KafriLabLabel                   matlab.ui.control.Label
        Button_Plot_2                   matlab.ui.control.Button
        DisplayLabel                    matlab.ui.control.Label
        Button_Save_Application         matlab.ui.control.Button
        PreprocessLabel                 matlab.ui.control.Label
        Button_Preprocess               matlab.ui.control.Button
        Delete_Unicode                  matlab.ui.control.Button
        TabGroup4                       matlab.ui.container.TabGroup
        ScatterPlotwithTrendLinesTab    matlab.ui.container.Tab
        DataXaxisDropDownLabel          matlab.ui.control.Label
        DataXaxisDropDown               matlab.ui.control.DropDown
        Button_33                       matlab.ui.control.Button
        DansScatterPlotLabel            matlab.ui.control.Label
        PluginDocumentationPanel        matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        DataYaxisDropDownLabel          matlab.ui.control.Label
        DataYaxisDropDown               matlab.ui.control.DropDown
        TrendLineforEachDropDownLabel   matlab.ui.control.Label
        TrendLineforEachDropDown        matlab.ui.control.DropDown
        ParametersLabel                 matlab.ui.control.Label
        MedianforEachDropDownLabel      matlab.ui.control.Label
        MedianforEachDropDown           matlab.ui.control.DropDown
        DataYaxisDropDown_3Label        matlab.ui.control.Label
        DataYaxisDropDown_3             matlab.ui.control.DropDown
        DataXaxisDropDown_3Label        matlab.ui.control.Label
        DataXaxisDropDown_3             matlab.ui.control.DropDown
        ScatterBarPlotLoopTab           matlab.ui.container.Tab
        Button_34                       matlab.ui.control.Button
        DansScatterPlotLabel_2          matlab.ui.control.Label
        PluginDocumentationPanel_2      matlab.ui.container.Panel
        TextArea_2                      matlab.ui.control.TextArea
        DataXaxisDropDown_2Label        matlab.ui.control.Label
        DataXaxisDropDown_2             matlab.ui.control.DropDown
        DataYaxisDropDown_2Label        matlab.ui.control.Label
        DataYaxisDropDown_2             matlab.ui.control.DropDown
        ParametersLabel_2               matlab.ui.control.Label
        OnePlotPerDropDownLabel         matlab.ui.control.Label
        OnePlotPerDropDown              matlab.ui.control.DropDown
    end


    properties (Access = public)
        image % new currently displayed image
        image_info
        input_data
        plates
        image_names
        log_processing_message
        log_startup_message
        StartupLogTextArea
        segment
        segment_tabgp
        segment_names
        display
        measure
        measure_tabgp
        measure_overlay_color
        measure_overlay_size
        ResultTable
        ResultTable_for_display
        preprocess
        preprocess_tabgp
        analyze
        analyze_tabgp
        ProcessingLogQueue
        processing_running
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('functions'));
            addpath(genpath('plugins'));
            startup_fnc(app,@createCallbackFcn);
            %delete(startup_f); % it's not working. Refactor the startup log into the platemap loading log and display the log textbox in the input tab while loading and delete when done. Right now it only displays after it's useful, after the loading is done
             
        end

        % Callback function
        function UITableCellEdit(app, event)
            update_plot(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            c = uisetcolor([1 1 0],'Select a color');
        end

        % Button pushed function: Button_Segment
        function Button_SegmentPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Segment;
        end

        % Button pushed function: Button_Input
        function Button_InputPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Input;
        end

        % Button pushed function: Button_Measure
        function Button_MeasurePushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Measure;
        end

        % Button pushed function: Button_Track
        function Button_TrackPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Track;
            %uialert(app.UIFigure,'Sorry, this feature has not yet been implemented.', 'Feature Not Implemented', 'Icon','warning');
        end

        % Button pushed function: Button_Plot
        function Button_PlotPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Analyze;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           browse_button_pushed(app, @createCallbackFcn);
        end

        % Button pushed function: Button_StartProcessing
        function Button_StartProcessingPushed(app, event)
            %% EXECUTE MAIN PROCESSING
            start_processing(app);
            %pause(1); % maybe this will fix partially loaded ResultTable in openvar() with a large ResultTable and parallel processing, need to test more. UPDATE: too much laggy stats
            assignin('base','ResultTable',app.ResultTable);
            %evalin('base','openvar(''ResultTable'')'); 
        end

        % Button pushed function: Button_Plot_2
        function Button_Display_Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Display;
            if ~isempty(app.input_data)
              update_figure(app);
            end
        end

        % Button pushed function: Button_AddSegment
        function Button_AddSegmentPushed(app, event)
            add_segment(app,@createCallbackFcn);
        end

        % Value changed function: PlateDropDown
        function PlateDropDownValueChanged(app, event)
            changed_PlateDropDown(app);
        end

        % Value changed function: RowDropDown
        function RowDropDownValueChanged(app, event)
            value = app.RowDropDown.Value;
            start_processing_of_one_image(app);
            update_figure(app);
            app.log_processing_message(app, 'Finished.');
        end

        % Value changed function: ColumnDropDown
        function ColumnDropDownValueChanged(app, event)
            value = app.ColumnDropDown.Value;
            start_processing_of_one_image(app);
            update_figure(app);
            app.log_processing_message(app, 'Finished.');
        end

        % Value changed function: FieldDropDown
        function FieldDropDownValueChanged(app, event)
            value = app.FieldDropDown.Value;
            start_processing_of_one_image(app);
            update_figure(app);
            app.log_processing_message(app, 'Finished.');
        end

        % Value changed function: TimepointDropDown
        function TimepointDropDownValueChanged(app, event)
            value = app.TimepointDropDown.Value;
            start_processing_of_one_image(app);
            update_figure(app);
            app.log_processing_message(app, 'Finished.');
        end

        % Button pushed function: Button_SaveFigure
        function Button_SaveFigurePushed(app, event)
            save_dir = uigetdir(app.ChooseplatemapEditField.Value,'Select Directory to Save Figure In');
            date_str = datestr(now,'yyyymmddTHHMMSS');
            filename = [save_dir '/figure_' date_str '.png'];
            mag = num2str(app.AtMagnificationSpinner.Value);
            export_fig(filename, ['-m' mag]);
            myicon = imread(filename);
            msgbox(sprintf('Saved the current figure to the file "%s" at %sx the resolution that see on your screen.',filename,mag),'Success','custom',myicon);
        end

        % Button pushed function: Button_AddMeasure
        function Button_AddMeasurePushed(app, event)
            add_measure(app, @createCallbackFcn);
        end

        % Button pushed function: Button_BrowseHelp
        function Button_BrowseHelpPushed(app, event)
            message = sprintf('You must choose a plate map to describe your experiment. It is a spreadsheet the contains the location to images, the channel names, the plate well layout of your experiments, and any other metadata that you choose to include. To learn more about the format of the spreadsheet please see the documentation at http://thedocumentation.com.');
            uialert(app.UIFigure,message,'Browse a plate map',...
            'Icon','info');
        end

        % Button pushed function: Button_PrimarySegmentHelp
        function Button_PrimarySegmentHelpPushed(app, event)
            msg = 'The primary segment is the top-level segment that other segments (sub-segments) must be contained within. Typically a cell would be a primary segment and a nucleus would be a sub-segment. Importantly, measurements recorded will be on a primary segment basis. This means that in the measurements result table, each row is in the table corrosponds to one primary segment. Measruments on sub-segments are stored as columns in the row of the primary segment.';
            uialert(app.UIFigure,msg,'Primary Segment', 'Icon','info');
            %             load('app.mat')
            %             evalin('caller','load(''app.mat'')')
            %             evalin('base','load(''app.mat'')')
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            update_figure(app);
        end

        % Value changed function: DisplayMeasureCheckBox
        function DisplayMeasureCheckBoxValueChanged(app, event)
            value = app.DisplayMeasureCheckBox.Value;
            update_figure(app);
        end

        % Button pushed function: DisplayMeasureColorButton
        function DisplayMeasureColorButtonPushed(app, event)
            %app.display.channel_override = false;
            current_RGB = app.measure_overlay_color;
            new_RGB = uisetcolor(current_RGB);
            app.measure_overlay_color = new_RGB;
            update_figure(app);
        end

        % Value changed function: DisplayMeasureFontSize
        function DisplayMeasureFontSizeValueChanged(app, event)
            value = app.DisplayMeasureFontSize.Value;
            update_figure(app);
        end

        % Value changed function: DisplayMeasureDropDown
        function DisplayMeasureDropDownValueChanged(app, event)
            value = app.DisplayMeasureDropDown.Value;
            app.DisplayMeasureCheckBox.Value = true;
            update_figure(app);
        end

        % Callback function
        function Button_33Pushed(app, event)
            uialert(app.UIFigure,'test','Unkown image naming scheme', 'Icon','error');
            app.processing_running = false;
        end

        % Button pushed function: Button_ViewMeasurements
        function Button_ViewMeasurementsPushed(app, event)
            assignin('base','ResultTable',app.ResultTable);
            evalin('base','openvar(''ResultTable'')'); 
        end

        % Button pushed function: Button_ExportMeasurements
        function Button_ExportMeasurementsPushed(app, event)
            ResultTable = app.ResultTable;
            uisave('ResultTable');
        end

        % Button pushed function: Button_Save_Application
        function Button_Save_ApplicationPushed(app, event)
            saved_app = app;
            uisave('saved_app');
        end

        % Button pushed function: Button_Preprocess
        function Button_PreprocessPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Preprocess;
        end

        % Button pushed function: Button_AddProprocess
        function Button_AddProprocessPushed(app, event)
            add_preprocess(app,@createCallbackFcn);
        end

        % Value changed function: ExperimentDropDown
        function ExperimentDropDownValueChanged(app, event)
            value = app.ExperimentDropDown.Value;
            changed_ExperimentDropDown(app);
        end

        % Button pushed function: Button_AddAnalysis
        function Button_AddAnalysisPushed(app, event)
            add_analyze(app,@createCallbackFcn);
        end

        % Button pushed function: Button_SaveFigure_2
        function Button_SaveFigure_2Pushed(app, event)
            save_montage(app);
        end

        % Value changed function: MontageMovieCheckBox
        function MontageMovieCheckBoxValueChanged(app, event)
            value = app.MontageMovieCheckBox.Value;
            if value
                app.MovieatFPSSpinner.Enable = 'on';
                app.MovieatFPSSpinnerLabel.Enable = 'on';
            else
                app.MovieatFPSSpinner.Enable = 'off';
                app.MovieatFPSSpinnerLabel.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Input = uitab(app.TabGroup);
            app.Tab_Input.Title = '                          ';
            app.BrowseButton = uibutton(app.Tab_Input, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ChooseplatemapEditField = uieditfield(app.Tab_Input, 'text');
            app.ChooseplatemapEditField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.Tab_Input);
            app.Plate1Tab = uitab(app.TabGroup3);
            app.Plate1Tab.Title = 'Plate 1';
            app.PathtoImagesEditField = uieditfield(app.Plate1Tab, 'text');
            app.PathtoImagesEditField.Editable = 'off';
            app.UITable = uitable(app.Plate1Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.Plate1Tab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.PathtoImagesLabel = uilabel(app.Plate1Tab);
            app.PathtoImagesLabel.Text = 'Path to Images:';
            app.EditField = uieditfield(app.Plate1Tab, 'text');
            app.RowsLabel = uilabel(app.Plate1Tab);
            app.RowsLabel.Text = 'Rows:';
            app.ColumnsLabel = uilabel(app.Plate1Tab);
            app.ColumnsLabel.Text = 'Columns:';
            app.EditField_2 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_3 = uieditfield(app.Plate1Tab, 'text');
            app.FieldsLabel_2 = uilabel(app.Plate1Tab);
            app.FieldsLabel_2.Text = 'Fields:';
            app.EditField_4 = uieditfield(app.Plate1Tab, 'text');
            app.TimepointsLabel_3 = uilabel(app.Plate1Tab);
            app.TimepointsLabel_3.Text = 'Timepoints:';
            app.Button_29 = uibutton(app.Plate1Tab, 'push');
            app.Button_29.Icon = 'question-sign.png';
            app.Button_29.Text = '';
            app.PlateMetadataLabel = uilabel(app.Plate1Tab);
            app.PlateMetadataLabel.Text = 'Plate Metadata:';
            app.PlateMapLabel_2 = uilabel(app.Plate1Tab);
            app.PlateMapLabel_2.Text = 'Plate Map:';
            app.FilterInputLabel_2 = uilabel(app.Plate1Tab);
            app.FilterInputLabel_2.Text = 'Filter Input:';
            app.ImageChannelsLabel = uilabel(app.Plate1Tab);
            app.ImageChannelsLabel.Text = 'Image Channels';
            app.Button_30 = uibutton(app.Plate1Tab, 'push');
            app.Button_30.Icon = 'question-sign.png';
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.Plate1Tab, 'push');
            app.Button_31.Icon = 'question-sign.png';
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.Plate1Tab, 'push');
            app.Button_32.Icon = 'question-sign.png';
            app.Button_32.Text = '';
            app.ListBox_2 = uilistbox(app.Plate1Tab);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.NumberofImagesLabel = uilabel(app.Plate1Tab);
            app.NumberofImagesLabel.Text = 'Number of Images:';
            app.EditField_5 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_5.Editable = 'off';
            app.Button_BrowseHelp = uibutton(app.Tab_Input, 'push');
            app.Button_BrowseHelp.ButtonPushedFcn = createCallbackFcn(app, @Button_BrowseHelpPushed, true);
            app.Button_BrowseHelp.Icon = 'question-sign.png';
            app.Button_BrowseHelp.Text = '';
            app.ChooseplatemaporsavedstateLabel = uilabel(app.Tab_Input);
            app.ChooseplatemaporsavedstateLabel.Text = {'Choose plate map'; 'or saved state:'};
            app.Tab_Display = uitab(app.TabGroup);
            app.Tab_Display.Title = '                          ';
            app.DisplaySettingsLabel = uilabel(app.Tab_Display);
            app.DisplaySettingsLabel.Text = 'Display Settings';
            app.Panel = uipanel(app.Tab_Display);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.ImageSelectionLabel = uilabel(app.Panel);
            app.ImageSelectionLabel.Text = 'Image Selection';
            app.ChannelsLabel = uilabel(app.Panel);
            app.ChannelsLabel.Text = 'Channels';
            app.SegmentOverlayLabel = uilabel(app.Panel);
            app.SegmentOverlayLabel.Text = 'Segment Overlay';
            app.MetricOverlayLabel = uilabel(app.Panel);
            app.MetricOverlayLabel.Text = 'Metric Overlay';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.Icon = 'camera.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.Button_16 = uibutton(app.Panel, 'push');
            app.Button_16.Icon = '010-check.png';
            app.Button_16.Enable = 'off';
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.Panel, 'push');
            app.Button_17.Enable = 'off';
            app.Button_17.Text = '🏿';
            app.Button_18 = uibutton(app.Panel, 'push');
            app.Button_18.Enable = 'off';
            app.Button_18.Text = '📏';
            app.ColumnDropDownLabel = uilabel(app.Panel);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.Panel);
            app.ColumnDropDown.Editable = 'on';
            app.ColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnDropDownValueChanged, true);
            app.FieldDropDownLabel = uilabel(app.Panel);
            app.FieldDropDownLabel.Text = 'Field';
            app.TimepointDropDownLabel = uilabel(app.Panel);
            app.TimepointDropDownLabel.Text = 'Timepoint';
            app.FieldDropDown = uidropdown(app.Panel);
            app.FieldDropDown.Editable = 'on';
            app.FieldDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldDropDownValueChanged, true);
            app.TimepointDropDown = uidropdown(app.Panel);
            app.TimepointDropDown.Editable = 'on';
            app.TimepointDropDown.ValueChangedFcn = createCallbackFcn(app, @TimepointDropDownValueChanged, true);
            app.Button_SaveFigure = uibutton(app.Panel, 'push');
            app.Button_SaveFigure.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveFigurePushed, true);
            app.Button_SaveFigure.Icon = 'download-to-storage-drive.png';
            app.Button_SaveFigure.Text = 'Save Figure';
            app.RowDropDownLabel = uilabel(app.Panel);
            app.RowDropDownLabel.Text = 'Row';
            app.RowDropDown = uidropdown(app.Panel);
            app.RowDropDown.Editable = 'on';
            app.RowDropDown.ValueChangedFcn = createCallbackFcn(app, @RowDropDownValueChanged, true);
            app.ExperimentDropDownLabel = uilabel(app.Panel);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.Panel);
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentDropDownValueChanged, true);
            app.PlateDropDownLabel = uilabel(app.Panel);
            app.PlateDropDownLabel.Text = 'Plate';
            app.PlateDropDown = uidropdown(app.Panel);
            app.PlateDropDown.ValueChangedFcn = createCallbackFcn(app, @PlateDropDownValueChanged, true);
            app.AtMagnificationSpinnerLabel = uilabel(app.Panel);
            app.AtMagnificationSpinnerLabel.Text = 'At Magnification';
            app.AtMagnificationSpinner = uispinner(app.Panel);
            app.AtMagnificationSpinner.Limits = [1 10];
            app.AtMagnificationSpinner.Value = 1;
            app.DisplayMeasureDropDown = uidropdown(app.Panel);
            app.DisplayMeasureDropDown.Items = {};
            app.DisplayMeasureDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayMeasureDropDownValueChanged, true);
            app.DisplayMeasureDropDown.Value = {};
            app.DisplayMeasureCheckBox = uicheckbox(app.Panel);
            app.DisplayMeasureCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayMeasureCheckBoxValueChanged, true);
            app.DisplayMeasureCheckBox.Text = '';
            app.DisplayMeasureColorButton = uibutton(app.Panel, 'push');
            app.DisplayMeasureColorButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMeasureColorButtonPushed, true);
            app.DisplayMeasureColorButton.Icon = 'painter-palette.png';
            app.DisplayMeasureColorButton.Text = '';
            app.DisplayMeasureFontSize = uispinner(app.Panel);
            app.DisplayMeasureFontSize.Limits = [1 Inf];
            app.DisplayMeasureFontSize.ValueChangedFcn = createCallbackFcn(app, @DisplayMeasureFontSizeValueChanged, true);
            app.DisplayMeasureFontSize.Value = 12;
            app.Button_SaveFigure_2 = uibutton(app.Panel, 'push');
            app.Button_SaveFigure_2.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveFigure_2Pushed, true);
            app.Button_SaveFigure_2.Icon = 'film-strip-with-two-photograms.png';
            app.Button_SaveFigure_2.Text = 'Save Montage';
            app.MovieatFPSSpinnerLabel = uilabel(app.Panel);
            app.MovieatFPSSpinnerLabel.Enable = 'off';
            app.MovieatFPSSpinnerLabel.Text = 'Movie at FPS';
            app.MovieatFPSSpinner = uispinner(app.Panel);
            app.MovieatFPSSpinner.Limits = [1 10];
            app.MovieatFPSSpinner.Enable = 'off';
            app.MovieatFPSSpinner.Value = 1;
            app.MontageMovieCheckBox = uicheckbox(app.Panel);
            app.MontageMovieCheckBox.ValueChangedFcn = createCallbackFcn(app, @MontageMovieCheckBoxValueChanged, true);
            app.MontageMovieCheckBox.Text = '';
            app.Tab_Preprocess = uitab(app.TabGroup);
            app.Tab_Preprocess.Title = '                          ';
            app.PreprocessInputImagesLabel = uilabel(app.Tab_Preprocess);
            app.PreprocessInputImagesLabel.Text = 'Preprocess Input Images';
            app.Button_AddProprocess = uibutton(app.Tab_Preprocess, 'push');
            app.Button_AddProprocess.ButtonPushedFcn = createCallbackFcn(app, @Button_AddProprocessPushed, true);
            app.Button_AddProprocess.Text = '➕ Add Preprocess';
            app.AddapreprocesssteptogetstartedLabel = uilabel(app.Tab_Preprocess);
            app.AddapreprocesssteptogetstartedLabel.Text = 'Add a preprocess step to get started.';
            app.Tab_Segment = uitab(app.TabGroup);
            app.Tab_Segment.Title = '                          ';
            app.Button_AddSegment = uibutton(app.Tab_Segment, 'push');
            app.Button_AddSegment.ButtonPushedFcn = createCallbackFcn(app, @Button_AddSegmentPushed, true);
            app.Button_AddSegment.Text = '➕ Add Segment';
            app.SegmentationSettingsLabel_2 = uilabel(app.Tab_Segment);
            app.SegmentationSettingsLabel_2.Text = 'Segmentation Settings';
            app.AddasegmenttogetstartedLabel = uilabel(app.Tab_Segment);
            app.AddasegmenttogetstartedLabel.Text = 'Add a segment to get started.';
            app.Tab_Measure = uitab(app.TabGroup);
            app.Tab_Measure.Title = '                          ';
            app.Button_StartProcessing = uibutton(app.Tab_Measure, 'push');
            app.Button_StartProcessing.ButtonPushedFcn = createCallbackFcn(app, @Button_StartProcessingPushed, true);
            app.Button_StartProcessing.Icon = 'play-button.png';
            app.Button_StartProcessing.Text = 'Start Processing';
            app.ProcessingLogTextArea = uitextarea(app.Tab_Measure);
            app.ProcessingLogTextArea.Editable = 'off';
            app.ProcessingLogTextAreaLabel = uilabel(app.Tab_Measure);
            app.ProcessingLogTextAreaLabel.Text = 'Processing Log';
            app.PrimarySegmentDropDownLabel = uilabel(app.Tab_Measure);
            app.PrimarySegmentDropDownLabel.Text = 'Primary Segment:';
            app.PrimarySegmentDropDown = uidropdown(app.Tab_Measure);
            app.PrimarySegmentDropDown.Items = {};
            app.PrimarySegmentDropDown.Value = {};
            app.SelectMeasurementsLabel = uilabel(app.Tab_Measure);
            app.SelectMeasurementsLabel.Text = 'Select Measurements';
            app.Button_PrimarySegmentHelp = uibutton(app.Tab_Measure, 'push');
            app.Button_PrimarySegmentHelp.ButtonPushedFcn = createCallbackFcn(app, @Button_PrimarySegmentHelpPushed, true);
            app.Button_PrimarySegmentHelp.Icon = 'question-sign.png';
            app.Button_PrimarySegmentHelp.Text = '';
            app.ProgressSlider = uislider(app.Tab_Measure);
            app.ProgressSlider.Limits = [0 1];
            app.ProgressSlider.MajorTicks = [];
            app.ProgressSlider.MajorTickLabels = {};
            app.ProgressSlider.MinorTicks = [];
            app.ProgressSlider.Enable = 'off';
            app.Button_AddMeasure = uibutton(app.Tab_Measure, 'push');
            app.Button_AddMeasure.ButtonPushedFcn = createCallbackFcn(app, @Button_AddMeasurePushed, true);
            app.Button_AddMeasure.Text = '➕ Add Measurement';
            app.CheckBox_Parallel = uicheckbox(app.Tab_Measure);
            app.CheckBox_Parallel.Text = 'Parallelize';
            app.CheckBox_TestRun = uicheckbox(app.Tab_Measure);
            app.CheckBox_TestRun.Text = 'Only One';
            app.Button_ViewMeasurements = uibutton(app.Tab_Measure, 'push');
            app.Button_ViewMeasurements.ButtonPushedFcn = createCallbackFcn(app, @Button_ViewMeasurementsPushed, true);
            app.Button_ViewMeasurements.Icon = 'list.png';
            app.Button_ViewMeasurements.Text = 'View Measurements';
            app.Button_ExportMeasurements = uibutton(app.Tab_Measure, 'push');
            app.Button_ExportMeasurements.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportMeasurementsPushed, true);
            app.Button_ExportMeasurements.Icon = 'download-to-storage-drive.png';
            app.Button_ExportMeasurements.Text = 'Save Measurements';
            app.AddameasurementtogetstartedLabel = uilabel(app.Tab_Measure);
            app.AddameasurementtogetstartedLabel.Text = 'Add a measurement to get started.';
            app.Tab_Track = uitab(app.TabGroup);
            app.Tab_Track.Title = '                          ';
            app.Tab_Analyze = uitab(app.TabGroup);
            app.Tab_Analyze.Title = '                          ';
            app.AnalyzeYourResultsLabel = uilabel(app.Tab_Analyze);
            app.AnalyzeYourResultsLabel.Text = 'Analyze Your Results';
            app.AddananalysistogetstartedLabel = uilabel(app.Tab_Analyze);
            app.AddananalysistogetstartedLabel.Text = 'Add an analysis to get started.';
            app.Button_AddAnalysis = uibutton(app.Tab_Analyze, 'push');
            app.Button_AddAnalysis.ButtonPushedFcn = createCallbackFcn(app, @Button_AddAnalysisPushed, true);
            app.Button_AddAnalysis.Text = '➕ Add Analysis';
            app.Button_RunAllAnalysis = uibutton(app.Tab_Analyze, 'push');
            app.Button_RunAllAnalysis.Icon = 'play-button.png';
            app.Button_RunAllAnalysis.Text = 'Run All Analysis';
            app.SegmentLabel = uilabel(app.UIFigure);
            app.SegmentLabel.Text = 'Segment';
            app.MadewithinTorontoLabel = uilabel(app.UIFigure);
            app.MadewithinTorontoLabel.Text = 'Made with 💛 in Toronto';
            app.Button_Segment = uibutton(app.UIFigure, 'push');
            app.Button_Segment.ButtonPushedFcn = createCallbackFcn(app, @Button_SegmentPushed, true);
            app.Button_Segment.Text = '🏿';
            app.MeasureLabel = uilabel(app.UIFigure);
            app.MeasureLabel.Text = 'Measure';
            app.TrackLabel = uilabel(app.UIFigure);
            app.TrackLabel.Text = 'Track';
            app.AnalyzeLabel = uilabel(app.UIFigure);
            app.AnalyzeLabel.Text = 'Analyze';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.Button_Input = uibutton(app.UIFigure, 'push');
            app.Button_Input.ButtonPushedFcn = createCallbackFcn(app, @Button_InputPushed, true);
            app.Button_Input.Icon = 'open-folder.png';
            app.Button_Input.Text = '';
            app.Button_Plot = uibutton(app.UIFigure, 'push');
            app.Button_Plot.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotPushed, true);
            app.Button_Plot.Icon = 'dot-plot.png';
            app.Button_Plot.Text = '';
            app.Button_Track = uibutton(app.UIFigure, 'push');
            app.Button_Track.ButtonPushedFcn = createCallbackFcn(app, @Button_TrackPushed, true);
            app.Button_Track.Text = '➠';
            app.Button_Measure = uibutton(app.UIFigure, 'push');
            app.Button_Measure.ButtonPushedFcn = createCallbackFcn(app, @Button_MeasurePushed, true);
            app.Button_Measure.Text = '📏';
            app.SingleCellAnalysisWorkflowLabel = uilabel(app.UIFigure);
            app.SingleCellAnalysisWorkflowLabel.Text = 'Single Cell Analysis Workflow';
            app.KafriLabLabel = uilabel(app.UIFigure);
            app.KafriLabLabel.Text = '@Kafri-Lab 🔬';
            app.Button_Plot_2 = uibutton(app.UIFigure, 'push');
            app.Button_Plot_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Display_Pushed, true);
            app.Button_Plot_2.Icon = 'desktop-monitor (1).png';
            app.Button_Plot_2.Text = '';
            app.DisplayLabel = uilabel(app.UIFigure);
            app.DisplayLabel.Text = 'Display';
            app.Button_Save_Application = uibutton(app.UIFigure, 'push');
            app.Button_Save_Application.ButtonPushedFcn = createCallbackFcn(app, @Button_Save_ApplicationPushed, true);
            app.Button_Save_Application.Icon = 'download-to-storage-drive.png';
            app.Button_Save_Application.Text = 'Save Application State';
            app.PreprocessLabel = uilabel(app.UIFigure);
            app.PreprocessLabel.Text = 'Preprocess';
            app.Button_Preprocess = uibutton(app.UIFigure, 'push');
            app.Button_Preprocess.ButtonPushedFcn = createCallbackFcn(app, @Button_PreprocessPushed, true);
            app.Button_Preprocess.Icon = 'darker-or-brightter-button.png';
            app.Button_Preprocess.Text = '';
            app.Delete_Unicode = uibutton(app.UIFigure, 'push');
            app.Delete_Unicode.Text = '❌';
            app.TabGroup4 = uitabgroup(app.UIFigure);
            app.ScatterPlotwithTrendLinesTab = uitab(app.TabGroup4);
            app.ScatterPlotwithTrendLinesTab.Title = 'Scatter Plot with Trend Lines';
            app.DataXaxisDropDownLabel = uilabel(app.ScatterPlotwithTrendLinesTab);
            app.DataXaxisDropDownLabel.Text = 'Data X-axis';
            app.DataXaxisDropDown = uidropdown(app.ScatterPlotwithTrendLinesTab);
            app.DataXaxisDropDown.Items = {'p-HA2X', 'Option 2', 'Option 3', 'Option 4'};
            app.DataXaxisDropDown.Value = 'p-HA2X';
            app.Button_33 = uibutton(app.ScatterPlotwithTrendLinesTab, 'push');
            app.Button_33.Icon = 'figgD.JPG';
            app.Button_33.Text = '';
            app.DansScatterPlotLabel = uilabel(app.ScatterPlotwithTrendLinesTab);
            app.DansScatterPlotLabel.Text = 'Dan''s Scatter Plot';
            app.PluginDocumentationPanel = uipanel(app.ScatterPlotwithTrendLinesTab);
            app.PluginDocumentationPanel.Title = 'Plugin Documentation';
            app.TextArea = uitextarea(app.PluginDocumentationPanel);
            app.TextArea.Value = {'Light Theme'; 'Single Trendline'; ''};
            app.DataYaxisDropDownLabel = uilabel(app.ScatterPlotwithTrendLinesTab);
            app.DataYaxisDropDownLabel.Text = 'Data Y-axis';
            app.DataYaxisDropDown = uidropdown(app.ScatterPlotwithTrendLinesTab);
            app.DataYaxisDropDown.Items = {'pS6', 'Option 2', 'Option 3', 'Option 4'};
            app.DataYaxisDropDown.Value = 'pS6';
            app.TrendLineforEachDropDownLabel = uilabel(app.ScatterPlotwithTrendLinesTab);
            app.TrendLineforEachDropDownLabel.Text = 'Trend Line for Each';
            app.TrendLineforEachDropDown = uidropdown(app.ScatterPlotwithTrendLinesTab);
            app.TrendLineforEachDropDown.Items = {'Experiment', 'Option 2', 'Option 3', 'Option 4'};
            app.TrendLineforEachDropDown.Value = 'Experiment';
            app.ParametersLabel = uilabel(app.ScatterPlotwithTrendLinesTab);
            app.ParametersLabel.Text = 'Parameters';
            app.MedianforEachDropDownLabel = uilabel(app.ScatterPlotwithTrendLinesTab);
            app.MedianforEachDropDownLabel.Text = 'Median for Each';
            app.MedianforEachDropDown = uidropdown(app.ScatterPlotwithTrendLinesTab);
            app.MedianforEachDropDown.Items = {'Animal', 'Option 2', 'Option 3', 'Option 4'};
            app.MedianforEachDropDown.Value = 'Animal';
            app.DataYaxisDropDown_3Label = uilabel(app.ScatterPlotwithTrendLinesTab);
            app.DataYaxisDropDown_3Label.Text = 'Data Y-axis';
            app.DataYaxisDropDown_3 = uidropdown(app.ScatterPlotwithTrendLinesTab);
            app.DataYaxisDropDown_3.Items = {'Lifespan', 'Option 2', 'Option 3', 'Option 4'};
            app.DataYaxisDropDown_3.Value = 'Lifespan';
            app.DataXaxisDropDown_3Label = uilabel(app.ScatterPlotwithTrendLinesTab);
            app.DataXaxisDropDown_3Label.Text = 'Data X-axis';
            app.DataXaxisDropDown_3 = uidropdown(app.ScatterPlotwithTrendLinesTab);
            app.DataXaxisDropDown_3.Items = {'Cellsize', 'Option 2', 'Option 3', 'Option 4'};
            app.DataXaxisDropDown_3.Value = 'Cellsize';
            app.ScatterBarPlotLoopTab = uitab(app.TabGroup4);
            app.ScatterBarPlotLoopTab.Title = 'Scatter Bar Plot Loop';
            app.Button_34 = uibutton(app.ScatterBarPlotLoopTab, 'push');
            app.Button_34.Icon = 'figg.JPG';
            app.Button_34.Text = '';
            app.DansScatterPlotLabel_2 = uilabel(app.ScatterBarPlotLoopTab);
            app.DansScatterPlotLabel_2.Text = 'Dan''s Scatter Plot';
            app.PluginDocumentationPanel_2 = uipanel(app.ScatterBarPlotLoopTab);
            app.PluginDocumentationPanel_2.Title = 'Plugin Documentation';
            app.TextArea_2 = uitextarea(app.PluginDocumentationPanel_2);
            app.TextArea_2.Value = {'Light Theme'; 'Single Trendline'; ''};
            app.DataXaxisDropDown_2Label = uilabel(app.ScatterBarPlotLoopTab);
            app.DataXaxisDropDown_2Label.Text = 'Data X-axis';
            app.DataXaxisDropDown_2 = uidropdown(app.ScatterBarPlotLoopTab);
            app.DataXaxisDropDown_2.Items = {'Experiment', 'Option 2', 'Option 3', 'Option 4'};
            app.DataXaxisDropDown_2.Value = 'Experiment';
            app.DataYaxisDropDown_2Label = uilabel(app.ScatterBarPlotLoopTab);
            app.DataYaxisDropDown_2Label.Text = 'Data Y-axis';
            app.DataYaxisDropDown_2 = uidropdown(app.ScatterBarPlotLoopTab);
            app.DataYaxisDropDown_2.Items = {'HA2X', 'Option 2', 'Option 3', 'Option 4'};
            app.DataYaxisDropDown_2.Value = 'HA2X';
            app.ParametersLabel_2 = uilabel(app.ScatterBarPlotLoopTab);
            app.ParametersLabel_2.Text = 'Parameters';
            app.OnePlotPerDropDownLabel = uilabel(app.ScatterBarPlotLoopTab);
            app.OnePlotPerDropDownLabel.Text = 'One Plot Per';
            app.OnePlotPerDropDown = uidropdown(app.ScatterBarPlotLoopTab);
            app.OnePlotPerDropDown.Items = {'Cell Line', 'Option 2', 'Option 3', 'Option 4'};
            app.OnePlotPerDropDown.Value = 'Cell Line';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end